classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        TimeofDay24HrSlider           matlab.ui.control.Slider
        TimeofDay24HrSliderLabel      matlab.ui.control.Label
        HighLoadAreasListBox          matlab.ui.control.ListBox
        HighLoadAreasListBoxLabel     matlab.ui.control.Label
        RecommendedActionsTextArea    matlab.ui.control.TextArea
        TextAreaLabel                 matlab.ui.control.Label
        GymMapLabel                   matlab.ui.control.Label
        ToggleHeatMapButtonGroup      matlab.ui.container.ButtonGroup
        FloorAccelerationSwitch       matlab.ui.control.Switch
        FloorAccelerationSwitchLabel  matlab.ui.control.Label
        OccupantsSwitch               matlab.ui.control.Switch
        OccupantsSwitchLabel          matlab.ui.control.Label
        CurrentZoneLabel              matlab.ui.control.Label
        OccVsTime                     matlab.ui.control.UIAxes
        GymMap                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %% Loading our datasets
        data
        %% (Add Later) <--------
        state
        %% Areas of each Zone
        zonerectangles
    end
    
    methods (Access = private)
        
        function drawMap(app) % Drawing the rectangles for each zone
            cla(app.GymMap)
            hold(app.GymMap, 'on')
            
            % Zone 1
            app.zonerectangles(1) = rectangle(app.GymMap, 'Position',[20 5 16 20],'FaceColor','r','EdgeColor','black');
            xlim(app.GymMap, [0 60])
            ylim(app.GymMap, [0 30])
            axis(app.GymMap, "equal")
            axis(app.GymMap, "off")
            text(app.GymMap, 25, 15, "Cardio Zone","Color","white","BackgroundColor","black");

            % Zone 2
            app.zonerectangles(2) = rectangle(app.GymMap, 'Position',[36 15 19 10],'FaceColor','r','EdgeColor','black');
            xlim(app.GymMap, [0 60])
            ylim(app.GymMap, [0 30])
            axis(app.GymMap, "equal")
            axis(app.GymMap, "off")
            text(app.GymMap, 40, 20, "Free Weight Zone","Color","white","BackgroundColor","black");

            % Zone 3
            app.zonerectangles(3) = rectangle(app.GymMap, 'Position',[36 5 14 10],'FaceColor','green','EdgeColor','black');
            xlim(app.GymMap, [0 60])
            ylim(app.GymMap, [0 30])
            axis(app.GymMap, "equal")
            axis(app.GymMap, "off")
            text(app.GymMap, 38, 10, "Yoga Studio","Color","white","BackgroundColor","black");
   
            % Zone 4
            app.zonerectangles(4) = rectangle(app.GymMap, 'Position',[5 15 15 10],'FaceColor','blue','EdgeColor','black');
            xlim(app.GymMap, [0 60])
            ylim(app.GymMap, [0 30])
            axis(app.GymMap, "equal")
            axis(app.GymMap, "off")
            text(app.GymMap, 9, 20, "Locker Room","Color","white","BackgroundColor","black");
   
            % Zone 5
            app.zonerectangles(5) = rectangle(app.GymMap, 'Position',[6 5 14 10],'FaceColor','blue','EdgeColor','black');
            xlim(app.GymMap, [0 60])
            ylim(app.GymMap, [0 30])
            axis(app.GymMap, "equal")
            axis(app.GymMap, "off")
            text(app.GymMap, 8, 10, "Front Desk","Color","white","BackgroundColor","black");

        end

        
        function drawGraph(app)
            app.OccVsTime(plot(app.OccVsTime))
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Occupancy Data
            % Zone Occupancy
            occ = load("zone_occupancy.mat");
            app.data.occupancy = occ.zone_occupancy;
            % Zone Metadata
            meta = load("zone_metadata.mat");
            app.data.metadata = meta.zones;
            %% Floor Vibration Data
            % Structural Response Reference
            struct_ref = load("structural_response_reference.mat");
            app.data.structref = struct_ref.structural_response_reference;
            % Sensor Location Data
            sensor_loca = load("floor_vibration_sensor_locations.mat");
            app.data.sensoloca = sensor_loca.vibration_sensor_locations;
            % Vibration Acceleration
            vibe_accel = load("floor_vibration_acceleration.mat");
            app.data.vibeaccel = vibe_accel.floor_vibration;
            % Draws the Gym Map
            drawMap(app)
            % Draws Occ vs. Time Graph
            drawGraph(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GymMap = uiaxes(app.GridLayout);
            app.GymMap.XTick = [];
            app.GymMap.YTick = [];
            app.OccVsTime = uiaxes(app.GridLayout);
            title(app.OccVsTime, 'Zone Occupants vs. Time of Day')
            xlabel(app.OccVsTime, 'Time of Day (24 Hr)')
            ylabel(app.OccVsTime, 'Occupants')
            zlabel(app.OccVsTime, 'Z')
            app.OccVsTime.XTick = [0 0.25 0.5 0.75 1];
            app.OccVsTime.XTickLabel = {'0'; '6:00'; '12:00'; '18:00'; '24:00'};
            app.OccVsTime.YTick = [0 1];
            app.OccVsTime.YTickLabel = {'0'; '30'};
            app.OccVsTime.Toolbar = [];
            app.CurrentZoneLabel = uilabel(app.GridLayout);
            app.CurrentZoneLabel.Text = '(Current Zone)';
            app.ToggleHeatMapButtonGroup = uibuttongroup(app.GridLayout);
            app.ToggleHeatMapButtonGroup.Title = 'Toggle Heat Map';
            app.OccupantsSwitchLabel = uilabel(app.ToggleHeatMapButtonGroup);
            app.OccupantsSwitchLabel.Text = 'Occupants';
            app.OccupantsSwitch = uiswitch(app.ToggleHeatMapButtonGroup, 'slider');
            app.OccupantsSwitch.Value = 'On';
            app.FloorAccelerationSwitchLabel = uilabel(app.ToggleHeatMapButtonGroup);
            app.FloorAccelerationSwitchLabel.Text = 'Floor Acceleration';
            app.FloorAccelerationSwitch = uiswitch(app.ToggleHeatMapButtonGroup, 'slider');
            app.FloorAccelerationSwitch.Value = 'On';
            app.GymMapLabel = uilabel(app.GridLayout);
            app.GymMapLabel.Text = 'Gym Map';
            app.TextAreaLabel = uilabel(app.GridLayout);
            app.TextAreaLabel.Text = 'Recommended Actions';
            app.RecommendedActionsTextArea = uitextarea(app.GridLayout);
            app.RecommendedActionsTextArea.Editable = 'off';
            app.HighLoadAreasListBoxLabel = uilabel(app.GridLayout);
            app.HighLoadAreasListBoxLabel.Text = 'High Load Areas';
            app.HighLoadAreasListBox = uilistbox(app.GridLayout);
            app.HighLoadAreasListBox.Items = {'(Current Load Index)'};
            app.HighLoadAreasListBox.ItemsData = {'(Current Load Data)'};
            app.HighLoadAreasListBox.Value = {};
            app.TimeofDay24HrSliderLabel = uilabel(app.GridLayout);
            app.TimeofDay24HrSliderLabel.Text = 'Time of  Day (24 Hr)';
            app.TimeofDay24HrSlider = uislider(app.GridLayout);
            app.TimeofDay24HrSlider.Limits = [0 24];
            app.TimeofDay24HrSlider.MajorTicks = [0 6 12 18 24];
            app.TimeofDay24HrSlider.MajorTickLabels = {'0:00', '6:00', '12:00', '18:00', '24:00'};
            app.TimeofDay24HrSlider.Step = 1;
            app.TimeofDay24HrSlider.MinorTicksMode = 'manual';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end