classdef app_interactive < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        TimeofDay24HrSlider         matlab.ui.control.Slider
        TimeofDay24HrSliderLabel    matlab.ui.control.Label
        HighLoadAreasListBox        matlab.ui.control.ListBox
        HighLoadAreasListBoxLabel   matlab.ui.control.Label
        RecommendedActionsTextArea  matlab.ui.control.TextArea
        TextAreaLabel               matlab.ui.control.Label
        GymMapLabel                 matlab.ui.control.Label
        GymMap                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %% Loading our datasets
        data
        %% Areas of each Zone
        zonerectangles = gobjects(5,1)

    end
    
    methods (Access = private)
        
        function drawMap(app) % Drawing the rectangles for each zone
            cla(app.GymMap)
            hold(app.GymMap, 'on')
            
            % Zone 1
            app.zonerectangles(1) = rectangle(app.GymMap, 'Position',[20 5 16 20],'FaceColor','r','EdgeColor','black');
            xlim(app.GymMap, [0 60])
            ylim(app.GymMap, [0 30])
            axis(app.GymMap, "equal")
            axis(app.GymMap, "off")
            text(app.GymMap, 25, 15, "Cardio Zone","Color","white","BackgroundColor","black");

            % Zone 2
            app.zonerectangles(2) = rectangle(app.GymMap, 'Position',[36 15 19 10],'FaceColor','r','EdgeColor','black');
            xlim(app.GymMap, [0 60])
            ylim(app.GymMap, [0 30])
            axis(app.GymMap, "equal")
            axis(app.GymMap, "off")
            text(app.GymMap, 40, 20, "Free Weight Zone","Color","white","BackgroundColor","black");

            % Zone 3
            app.zonerectangles(3) = rectangle(app.GymMap, 'Position',[36 5 14 10],'FaceColor','green','EdgeColor','black');
            xlim(app.GymMap, [0 60])
            ylim(app.GymMap, [0 30])
            axis(app.GymMap, "equal")
            axis(app.GymMap, "off")
            text(app.GymMap, 38, 10, "Yoga Studio","Color","white","BackgroundColor","black");
   
            % Zone 4
            app.zonerectangles(4) = rectangle(app.GymMap, 'Position',[5 15 15 10],'FaceColor','blue','EdgeColor','black');
            xlim(app.GymMap, [0 60])
            ylim(app.GymMap, [0 30])
            axis(app.GymMap, "equal")
            axis(app.GymMap, "off")
            text(app.GymMap, 9, 20, "Locker Room","Color","white","BackgroundColor","black");
   
            % Zone 5
            app.zonerectangles(5) = rectangle(app.GymMap, 'Position',[6 5 14 10],'FaceColor','blue','EdgeColor','black');
            xlim(app.GymMap, [0 60])
            ylim(app.GymMap, [0 30])
            axis(app.GymMap, "equal")
            axis(app.GymMap, "off")
            text(app.GymMap, 8, 10, "Front Desk","Color","white","BackgroundColor","black");
            

            % --- Inspect the datasets ---
disp("Occupancy class:")
class(app.data.occupancy)

disp("Occupancy size:")
size(app.data.occupancy)

disp("First few occupancy values:")
disp(app.data.occupancy)

disp("Vibration class:")
class(app.data.vibeaccel)

disp("Vibration:")
disp(app.data.vibeaccel)
        end
 
           
    function updateHeatMap(app, timeHour)

         disp("DATA TYPE:")
    disp(class(app.data))

    disp("OCCUPANCY TYPE:")
    disp(class(app.data.occupancy))

    occ = app.data.occupancy;

    timeSec = timeHour * 3600;

    idx = occ.time_sec == timeSec;

    if sum(idx) == 0
        return
    end

    zones = occ.zone_name(idx);
    people = occ.occupancy_count(idx);

  
 % Find high load areas at CURRENT selected time
highLoad = zones(people >= 10);

% Clear old values first
app.HighLoadAreasListBox.Items = {};

if isempty(highLoad)

    app.HighLoadAreasListBox.Items = {'No High Load Areas'};

else

    highLoad = strrep(highLoad,'_',' ');
    app.HighLoadAreasListBox.Items = highLoad';

end

    for i = 1:length(zones)

        zone = zones{i};
        count = people(i);

        % Determine color based on occupancy
        if count < 5
            color = [0 1 0];       % Green
        elseif count < 15
            color = [1 1 0];       % Yellow
        else
            color = [1 0 0];       % Red
        end


        % Change rectangle color
        switch zone

            case "cardio_zone"
                app.zonerectangles(1).FaceColor = color;

            case "free_weight_zone"
                app.zonerectangles(2).FaceColor = color;

            case "yoga_studio"
                app.zonerectangles(3).FaceColor = color;

            case "locker_room"
                app.zonerectangles(4).FaceColor = color;

            case "front_desk_lobby"
                app.zonerectangles(5).FaceColor = color;

        end
    end
    end
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Occupancy Data
            % Zone Occupancy
            occ = load("zone_occupancy.mat");
            app.data.occupancy = occ.zone_occupancy;
            % Zone Metadata
            meta = load("zone_metadata.mat");
            app.data.metadata = meta.zones;
            %% Floor Vibration Data
            % Structural Response Reference
            struct_ref = load("structural_response_reference.mat");
            app.data.structref = struct_ref.structural_response_reference;
            % Sensor Location Data
            sensor_loca = load("floor_vibration_sensor_locations.mat");
            app.data.sensoloca = sensor_loca.vibration_sensor_locations;
            % Vibration Acceleration
            vibe_accel = load("floor_vibration_acceleration.mat");
            app.data.vibeaccel = vibe_accel.floor_vibration;
            % Draws the Gym Map
            drawMap(app)
        end

        % Value changed function: TimeofDay24HrSlider
        function TimeofDay24HrSliderValueChanged(app, event)
            timeHour = round(app.TimeofDay24HrSlider.Value);

    updateHeatMap(app, timeHour);       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GymMap = uiaxes(app.GridLayout);
            app.GymMap.XTick = [];
            app.GymMap.YTick = [];
            app.GymMapLabel = uilabel(app.GridLayout);
            app.GymMapLabel.Text = 'Gym Map';
            app.TextAreaLabel = uilabel(app.GridLayout);
            app.TextAreaLabel.Text = 'Recommended Actions';
            app.RecommendedActionsTextArea = uitextarea(app.GridLayout);
            app.RecommendedActionsTextArea.Editable = 'off';
            app.HighLoadAreasListBoxLabel = uilabel(app.GridLayout);
            app.HighLoadAreasListBoxLabel.Text = 'High Load Areas';
            app.HighLoadAreasListBox = uilistbox(app.GridLayout);
            app.HighLoadAreasListBox.Items = {'(Current Load Index)'};
            app.HighLoadAreasListBox.ItemsData = {'(Current Load Data)'};
            app.HighLoadAreasListBox.Value = {};
            app.TimeofDay24HrSliderLabel = uilabel(app.GridLayout);
            app.TimeofDay24HrSliderLabel.Text = 'Time of  Day (24 Hr)';
            app.TimeofDay24HrSlider = uislider(app.GridLayout);
            app.TimeofDay24HrSlider.Limits = [0 24];
            app.TimeofDay24HrSlider.MajorTicks = [0 6 12 18 24];
            app.TimeofDay24HrSlider.MajorTickLabels = {'0:00', '6:00', '12:00', '18:00', '24:00'};
            app.TimeofDay24HrSlider.ValueChangedFcn = createCallbackFcn(app, @TimeofDay24HrSliderValueChanged, true);
            app.TimeofDay24HrSlider.Step = 1;
            app.TimeofDay24HrSlider.MinorTicksMode = 'manual';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_interactive

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end