classdef realfinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        ZoneDropDown                matlab.ui.control.DropDown
        ZoneDropDownLabel           matlab.ui.control.Label
        TimeofDay24HrSlider         matlab.ui.control.Slider
        TimeofDay24HrSliderLabel    matlab.ui.control.Label
        HighLoadAreasListBox        matlab.ui.control.ListBox
        HighLoadAreasListBoxLabel   matlab.ui.control.Label
        RecommendedActionsTextArea  matlab.ui.control.TextArea
        TextAreaLabel               matlab.ui.control.Label
        GymMapLabel                 matlab.ui.control.Label
        OccupancyGraph              matlab.ui.control.UIAxes
        GymMap                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %% Loading our datasets
        data
        %% Areas of each Zone
        zonerectangles = gobjects(5,1)
selectedZone = ""
    end

    methods (Access = private)

        function drawMap(app) % Drawing the rectangles for each zone
            cla(app.GymMap)
            hold(app.GymMap, 'on')
            
            % Zone 1
            app.zonerectangles(1) = rectangle(app.GymMap, 'Position',[20 5 16 20],'FaceColor','r','EdgeColor','black');
            xlim(app.GymMap, [0 60])
            ylim(app.GymMap, [0 30])
            axis(app.GymMap, "equal")
            axis(app.GymMap, "off")
            text(app.GymMap, 25, 15, "Cardio Zone","Color","white","BackgroundColor","black");

            'ButtonDownFcn';@(src,event) zoneClicked(app,"cardio_zone");
            % Zone 2
            app.zonerectangles(2) = rectangle(app.GymMap, 'Position',[36 15 19 10],'FaceColor','r','EdgeColor','black');
            xlim(app.GymMap, [0 60])
            ylim(app.GymMap, [0 30])
            axis(app.GymMap, "equal")
            axis(app.GymMap, "off")
            text(app.GymMap, 40, 20, "Free Weight Zone","Color","white","BackgroundColor","black");

           'ButtonDownFcn';@(src,event) zoneClicked(app,"free_weight_zone");
            % Zone 3
            app.zonerectangles(3) = rectangle(app.GymMap, 'Position',[36 5 14 10],'FaceColor','green','EdgeColor','black');
            xlim(app.GymMap, [0 60])
            ylim(app.GymMap, [0 30])
            axis(app.GymMap, "equal")
            axis(app.GymMap, "off")
            text(app.GymMap, 38, 10, "Yoga Studio","Color","white","BackgroundColor","black");
 
            'ButtonDownFcn';@(src,event) zoneClicked(app,"yoga_studio");
            % Zone 4
            app.zonerectangles(4) = rectangle(app.GymMap, 'Position',[5 15 15 10],'FaceColor','blue','EdgeColor','black');
            xlim(app.GymMap, [0 60])
            ylim(app.GymMap, [0 30])
            axis(app.GymMap, "equal")
            axis(app.GymMap, "off")
            text(app.GymMap, 9, 20, "Locker Room","Color","white","BackgroundColor","black");
 
            'ButtonDownFcn';@(src,event) zoneClicked(app,"locker_room");
            % Zone 5
            app.zonerectangles(5) = rectangle(app.GymMap, 'Position',[6 5 14 10],'FaceColor','blue','EdgeColor','black');
            xlim(app.GymMap, [0 60])
            ylim(app.GymMap, [0 30])
            axis(app.GymMap, "equal")
            axis(app.GymMap, "off")
            text(app.GymMap, 8, 10, "Front Desk","Color","white","BackgroundColor","black");
            

            'ButtonDownFcn';@(src,event) zoneClicked(app,"front_desk_lobby");
            % --- Inspect the datasets ---
disp("Occupancy class:")
class(app.data.occupancy)

disp("Occupancy size:")
size(app.data.occupancy)

disp("First few occupancy values:")
disp(app.data.occupancy)

disp("Vibration class:")
class(app.data.vibeaccel)

disp("Vibration:")
disp(app.data.vibeaccel)
        end
 
    function updateHeatMap(app, timeHour)

    occ = app.data.occupancy;

    % Convert hour to seconds
    timeSec = timeHour * 3600;

    % Find closest available timestamp
    allTimes = unique(occ.time_sec);
    [~,k] = min(abs(allTimes-timeSec));
    closestTime = allTimes(k);

    idx = occ.time_sec == closestTime;

    if ~any(idx)
        app.HighLoadAreasListBox.Items = {'No Data'};
        app.RecommendedActionsTextArea.Value = {'No recommendations available.'};
        return
    end

    zones = occ.zone_name(idx);
    people = occ.occupancy_count(idx);
    disp(table(zones, people))

    %% ---------------- High Load Areas ----------------
    highLoad = zones(people >= 8);

    if isempty(highLoad)
        app.HighLoadAreasListBox.Items = {'No High Load Areas'};
    else
        highLoad = strrep(highLoad,'_',' ');
        app.HighLoadAreasListBox.Items = highLoad';
    end

    %% ---------------- Recommended Actions ----------------
    actions = {};

    for i = 1:length(zones) 

        zone = zones{i};
        count = people(i);

        % Recommendations
        switch zone

           case 'cardio_zone'
    if count >= 15
        actions{end+1} = 'Redirect members to Free Weight Zone.';
    elseif count >= 8
        actions{end+1} = 'Monitor Cardio Zone traffic.';
    end

            case 'free_weight_zone'
                if count >= 15
                    actions{end+1} = 'Move 1 squat rack to Zone 2 entrance.';
                elseif count >= 8
                    actions{end+1} = 'Assign staff to Free Weight Zone.';
                end

            case 'yoga_studio'
                if count < 5
                    actions{end+1} = 'Recommend stretching in Yoga Studio.';
                end

            case 'locker_room'
                if count >= 8
                    actions{end+1} = 'Open additional lockers.';
                end

            case 'front_desk_lobby'
                if count >= 8
                    actions{end+1} = 'Open second check-in station.';
                end
        end

        %% Heat map colors
        if count < 4
            color = [0 1 0];
        elseif count < 10
            color = [1 1 0];
        else
            color = [1 0 0];
        end

        switch zone

            case 'cardio_zone'
                app.zonerectangles(1).FaceColor = color;

            case 'free_weight_zone'
                app.zonerectangles(2).FaceColor = color;

            case 'yoga_studio'
                app.zonerectangles(3).FaceColor = color;

            case 'locker_room'
                app.zonerectangles(4).FaceColor = color;

            case 'front_desk_lobby'
                app.zonerectangles(5).FaceColor = color;

        end
    end

    % Overall recommendation
    if sum(people >= 8) >= 2
        actions{end+1} = 'Redirect members to lower traffic areas.';
    end

    if isempty(actions)
        actions = {'No action required. Gym operating normally.'};
    end

    disp(actions)

    app.RecommendedActionsTextArea.Value = actions;

    end
    
   function plotOccupancy(app, zone)

    occ = app.data.occupancy;

    % Convert zone names to strings
    zoneNames = string(occ.zone_name);

    % Find matching rows
    idx = zoneNames == string(zone);

    % Debug
    disp("Selected zone:")
    disp(zone)

    disp("Available zones:")
    disp(unique(zoneNames))

    disp("Number of matches:")
    disp(sum(idx))

    % If no data
    if sum(idx) == 0
        cla(app.OccupancyGraph)
        title(app.OccupancyGraph,"No Data Found")
        return
    end

    % Pull data using logical indexing
    time = occ.time_sec(idx);
    people = occ.occupancy_count(idx);

    % Plot
    cla(app.OccupancyGraph)

    plot(app.OccupancyGraph,...
        time/3600,...
        people,...
        'LineWidth',2)

    xlabel(app.OccupancyGraph,"Time (Hours)")
    ylabel(app.OccupancyGraph,"People")

    title(app.OccupancyGraph,...
        strrep(string(zone),"_"," ") + " Occupancy")

    grid(app.OccupancyGraph,"on")

end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Occupancy Data
            % Zone Occupancy
            occ = load("zone_occupancy.mat");
            app.data.occupancy = occ.zone_occupancy;
            % Zone Metadata
            meta = load("zone_metadata.mat");
            app.data.metadata = meta.zones;
            %% Floor Vibration Data
            % Structural Response Reference
            struct_ref = load("structural_response_reference.mat");
            app.data.structref = struct_ref.structural_response_reference;
            % Sensor Location Data
            sensor_loca = load("floor_vibration_sensor_locations.mat");
            app.data.sensoloca = sensor_loca.vibration_sensor_locations;
            % Vibration Acceleration
            vibe_accel = load("floor_vibration_acceleration.mat");
            app.data.vibeaccel = vibe_accel.floor_vibration;
            % Draws the Gym Map
            drawMap(app)
        end

        % Value changed function: TimeofDay24HrSlider
        function TimeofDay24HrSliderValueChanged(app, event)

    timeHour = round(event.Value);
    updateHeatMap(app, timeHour);

        
        end

        % Value changed function: ZoneDropDown
        function ZoneDropDownValueChanged(app, event)


    dropdownValue = app.ZoneDropDown.Value;

    switch dropdownValue

        case "Cardio Zone"
            zone = "cardio_zone";

        case "Free Weight Zone"
            zone = "free_weight_zone";

        case "Yoga Studio"
            zone = "yoga_studio";

        case "Locker Room"
            zone = "locker_room";

        case "Front Desk"
            zone = "front_desk_lobby";

    end

    plotOccupancy(app, zone);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GymMap = uiaxes(app.GridLayout);
            app.GymMap.XTick = [];
            app.GymMap.YTick = [];
            app.OccupancyGraph = uiaxes(app.GridLayout);
            title(app.OccupancyGraph, 'OccupancyGraph')
            xlabel(app.OccupancyGraph, 'X')
            ylabel(app.OccupancyGraph, 'Y')
            zlabel(app.OccupancyGraph, 'Z')
            app.GymMapLabel = uilabel(app.GridLayout);
            app.GymMapLabel.Text = 'Gym Map';
            app.TextAreaLabel = uilabel(app.GridLayout);
            app.TextAreaLabel.Text = 'Recommended Actions';
            app.RecommendedActionsTextArea = uitextarea(app.GridLayout);
            app.RecommendedActionsTextArea.Editable = 'off';
            app.HighLoadAreasListBoxLabel = uilabel(app.GridLayout);
            app.HighLoadAreasListBoxLabel.Text = 'High Load Areas';
            app.HighLoadAreasListBox = uilistbox(app.GridLayout);
            app.HighLoadAreasListBox.Items = {'(Current Load Index)'};
            app.HighLoadAreasListBox.ItemsData = {'(Current Load Data)'};
            app.HighLoadAreasListBox.Value = {};
            app.TimeofDay24HrSliderLabel = uilabel(app.GridLayout);
            app.TimeofDay24HrSliderLabel.Text = 'Time of  Day (24 Hr)';
            app.TimeofDay24HrSlider = uislider(app.GridLayout);
            app.TimeofDay24HrSlider.Limits = [0 24];
            app.TimeofDay24HrSlider.MajorTicks = [0 6 12 18 24];
            app.TimeofDay24HrSlider.MajorTickLabels = {'0:00', '6:00', '12:00', '18:00', '24:00'};
            app.TimeofDay24HrSlider.ValueChangedFcn = createCallbackFcn(app, @TimeofDay24HrSliderValueChanged, true);
            app.TimeofDay24HrSlider.Step = 1;
            app.TimeofDay24HrSlider.MinorTicksMode = 'manual';
            app.ZoneDropDownLabel = uilabel(app.GridLayout);
            app.ZoneDropDownLabel.Text = 'Drop Down';
            app.ZoneDropDown = uidropdown(app.GridLayout);
            app.ZoneDropDown.Items = {'Cardio Zone', 'Free Weight Zone', 'Yoga Studio', 'Locker Room', 'Front Desk'};
            app.ZoneDropDown.ValueChangedFcn = createCallbackFcn(app, @ZoneDropDownValueChanged, true);
            app.ZoneDropDown.Value = 'Cardio Zone';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = realfinal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end